classdef Experiment4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CaseinformationPanel       matlab.ui.container.Panel
        DetailsLabel               matlab.ui.control.Label
        TituloLabel                matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        Timer                      matlab.ui.control.Label
        ExportButton               matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        SkipButton                 matlab.ui.control.Button
        TitleLabel                 matlab.ui.control.Label
        PauseButton                matlab.ui.control.StateButton
        SpeedButton                matlab.ui.control.Button
        WaveconditionsButtonGroup  matlab.ui.container.ButtonGroup
        Test1Button                matlab.ui.control.RadioButton
        Test2Button                matlab.ui.control.RadioButton
        Test3Button                matlab.ui.control.RadioButton
        ParticlePanel              matlab.ui.container.Panel
        WGCartVelocityDisplacementforx20mLabel  matlab.ui.control.Label
        XSensorsEditField          matlab.ui.control.NumericEditField
        xmLabel_4                  matlab.ui.control.Label
        LoadButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        WGAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Inicializar variables para todo el programa
        h = 0;
        H = 0;
        T = 0;
        beachX;
        beachZ;
        sand;
        caso_nom = '';
        data;   %Aqui se cargaran los datos. Ej. load caso1.mat
        skip = false;
        play_speed = 0;
        %WG1 = 0;
        %WG2 = 0;
        Loaded = false; % Cambia cuando se ha cargado el .mat
        Exportable = false; %Cambia cuando ya se hicieron los calculos
        etaWG1 = 0; % Variable para exportar
        xWG1 = 0; % Variable para exportar
        t = 0;      % Variable para exportar
    end
    
    methods (Access = private)
        
        function UpdateDetails(app)
            if app.Loaded == false
                app.Loaded = false;
                app.LoadButton.Text = 'Load';
                app.LoadButton.Enable = true;
            end
            %clear app.data;
            caso = app.WaveconditionsButtonGroup.SelectedObject.Text;
            switch caso
                case 'Test 1' %H=0.15 m; T=4 s; h=0.6 m 
                    app.TituloLabel.Text = "Test 1";
                    app.caso_nom = 'Experiment4/Case1.mat';
                    %app.beachX=[20 30 30 20];
                    app.H=0.15; app.T=4; app.h=0.6;
                case 'Test 2' %H=0.15 m; T=5 s; h=0.6 m
                    app.TituloLabel.Text = "Test 2";
                    app.caso_nom = 'Experiment4/Case2.mat';
                    %app.beachX=[0 30 30 0];
                    app.H=0.15; app.T=5; app.h=0.6;
                case 'Test 3' %H=0.15 m; T=6 s; h=0.6 m 
                    app.TituloLabel.Text = "Test 3";
                    app.caso_nom = 'Experiment4/Case3.mat';
                    %app.beachX=[0 30 30 0];
                    app.H=0.15; app.T=6; app.h=0.6;
                otherwise
                    app.TituloLabel.Text = 'Error, select Test case';
            end
            app.beachX =[20 30 30 20];
            app.beachZ =[0 1 0 0];
            app.sand = ([209 172 46]/256);
            %string = sprintf(['H= ',H,' m','\n','h= ',h,' m','\n','T= ',T,' s']);
            string = sprintf(['H= ',num2str(app.H),' m','\n','h= ',num2str(app.h),' m','\n','T= ',num2str(app.T),' s']);
            app.DetailsLabel.Text = string;
            
            %Actualiza el grafico del canal con los parametros del caso
            yy11=0:.2:1;
            yy22=-.6:.2:.4;
            yticks(app.UIAxes,yy11);
            yticklabels(app.UIAxes,yy22);
            
            
            X = [0 30 30 0];
            Z=[0 0 app.h app.h];
            water=[0 0 1];
            waves=fill(app.UIAxes,X,Z,water);
            alpha(waves,.7)
            hold(app.UIAxes,"on");
            beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
            hold(app.UIAxes,"off");
            app.ExportButton.Enable = false;
        end
        
        function EnableButtons(app,status)
            app.XSensorsEditField.Enable = status;
            app.Test1Button.Enable = status;
            app.Test2Button.Enable = status;
            app.Test3Button.Enable = status;
            app.StartButton.Enable = status;
            %app.ExportButton.Enable = status;
            if status == true
                app.play_speed = 0;
                app.SpeedButton.Text = '1x';
                %if (app.XSensorsEditField.Value > 0) || (app.ZSensorEditField.Value > 0)
                if app.Exportable == true
                    app.ExportButton.Enable = true;
                end
            else
                app.ExportButton.Enable = false;
            end
        end
        
        function PlotGraph(app)
            m=length(app.data.xc);
            n=length(app.data.yc);
            dt=0.1;
            N=length(app.data.time);

            WG1=20;
            VWG1=app.XSensorsEditField.Value;
            xWG1o=find(app.data.xc>WG1, 1 );

            water=[0 0 1];
            %j=0;

            
            rueda=0.025;
            xc1=-rueda:rueda/20:rueda;
            yc1=sqrt(rueda.^2-xc1.^2);
            ETA=zeros(1,length(app.data.time));
            xWG1=zeros(1,length(app.data.time));
            for i=0:N-1
                j=i+1;
                if i < 200
                    xWG1(j) = xWG1o;
                elseif i >= 200
                    xWG1(j) = find(app.data.xc>WG1-VWG1*(app.data.time(j)-20), 1 );
                end
                %disp(['El valor de xWG1 es= ',int2str(xWG1(j))]);
                %Se forza para que no llegue a 1 y no mida nada.
                if xWG1(j) == 1
                    xWG1(j) = 2;
                end
                ETA(j)= app.data.eta(j,xWG1(j))-app.data.eta(1,xWG1(j));
            end
            app.Exportable = true;
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'Along-flume distance, x (m)');
            ylabel(app.UIAxes,'Elevation (m)');
            xlabel(app.WGAxes,'Time (s)');
            ylabel(app.WGAxes,'\eta (m)');
            
            %Orden de Colores
            %1. 'k'
            %2. [0, 0.4470, 0.7410]
            %3. [0.8500, 0.3250, 0.0980]
            %4. [0.9290, 0.6940, 0.1250]
            %5. [0.4940, 0.1840, 0.5560]
            %6. [0.4660, 0.6740, 0.1880]
            %7. [0.3010, 0.7450, 0.9330]
            %8. [0.6350, 0.0780, 0.1840]
            
            %Inicia la Grafricacion
            WG_graf1=animatedline(app.WGAxes,'Color','k');
            waves=[];
            cart=[];
            for i=1:N
                %Grafica app.UIAxes canal
                if i == 1
                    beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                    hold(app.UIAxes,"on");
                end
                delete(cart);
                cart = [fill(app.UIAxes,[app.data.xc(xWG1(i))-1 app.data.xc(xWG1(i))+1 app.data.xc(xWG1(i))+1 app.data.xc(xWG1(i))-1],[app.h+0.2 app.h+0.2 app.h+0.22 app.h+0.22],'k')...
                           text(app.UIAxes,app.data.xc(xWG1(i)),0.9,'WG1')...
                           fill(app.UIAxes,[xc1 flip(xc1)]+app.data.xc(xWG1(i))-1+rueda,[-yc1 flip(yc1)]+app.h+0.2-rueda,'k')...
                           fill(app.UIAxes,[xc1 flip(xc1)]+app.data.xc(xWG1(i))+1-rueda,[-yc1 flip(yc1)]+app.h+0.2-rueda,'k')...
                           plot(app.UIAxes,[app.data.xc(xWG1(i)) app.data.xc(xWG1(i))],[app.h-0.1 app.h+0.20],'k','LineWidth',3)];
                delete(waves);
                waves=fill(app.UIAxes,[app.data.xc(1) app.data.xc' app.data.xc(end)],[0 app.data.eta(i,:) 0], water);
                alpha(waves,.7);
                delete(beach);
                beach=fill(app.UIAxes,app.beachX,app.beachZ,app.sand);
                app.Timer.Text = ['t= ' num2str(i*dt) ' s'];
                
                %Grafica WGAxes
                %ETA(j)= app.data.eta(j,xWG1)-app.data.eta(1,xWG1);
                %plot(app.WGAxes,app.data.time(1:j),ETA(1:j),'k')
                addpoints(WG_graf1,app.data.time(i),ETA(i));
                
                %hold(app.WGAxes,"on");
                if i == 1
                    axis(app.WGAxes,[0 50 -0.1 0.2]);
%                 else
%                     axis(app.WGAxes,[app.data.time(j)-50 app.data.time(j) -0.1 0.2]);
                elseif mod(i,500) == 0 && i ~= N
                    axis(app.WGAxes,[app.data.time(i) app.data.time(i)+50 -0.1 0.2]);
                end 
                %hold(app.WGAxes,"off");
                
                drawnow;
                
                
               %Verifica controles velocidad, pausa y salto
                if app.play_speed ~= 0
                    pause(app.play_speed);
                end
                while app.PauseButton.Value == true
                    pause(0.1);
                end
                if app.skip
                    app.skip = false;
                    break;
                end
            
                
            end
            legend(app.WGAxes,'WG1','Location','southwest');
            hold(app.UIAxes,"off");
            hold(app.WGAxes,"off");
            %Termina la graficacion

            %Variables a exportar
            app.etaWG1=ETA(200:end);
            %app.xWG1=xWG1(200:end);
            app.xWG1=app.data.xc(xWG1(200:end));
            app.t=app.data.time(200:end)-app.data.time(200);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center");
            app.UIFigure.Visible = 'on';
            
            %Carga los valores por default de un caso
            xlim(app.UIAxes,[0 30]);
            ylim(app.UIAxes,[0 1]);
            WaveconditionsButtonGroupSelectionChanged(app);
            
            %Establece labels de x y y de los graficos
            xlabel(app.UIAxes,'Along-flume distance, x (m)');
            ylabel(app.UIAxes,'Elevation (m)');
            xlabel(app.WGAxes,'Time (s)');
            ylabel(app.WGAxes,'\eta (m)');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Menu_CN;
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.WGAxes);
            legend(app.WGAxes,"off");
            app.UIAxes.Toolbar.Visible = 'off';
            if app.Loaded
                %app.StartButton.Enable = false;
                app.skip = false;
                %app.WG1 = 0;
                %app.WG2 = 0;
                EnableButtons(app,false);            
                PlotGraph(app);            
                EnableButtons(app,true); 
            end
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            app.skip = true;
            app.PauseButton.Value = false;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %Variables a exportar
            etaWG1 = app.etaWG1;
            xWG1 = app.xWG1;
            t = app.t;
            
            app.UIFigure.Visible = 'off';
            %Guardar datos
            if isunix
                file = '~/Downloads/data.mat';
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            elseif ispc
                userpath = getenv('USERPROFILE');
                file = [userpath,'/Downloads/data.mat'];
                [filename,dir_path]=uiputfile('*.mat','Save data',file);
            end
            app.UIFigure.Visible = 'on';
            if filename ~= 0
                %file=fullfile(dir_path,filename);
                save([dir_path filename], 't', 'xWG1', 'etaWG1');
            end
        end

        % Button pushed function: SpeedButton
        function SpeedButtonPushed(app, event)
            speed = app.SpeedButton.Text;
            if speed == "1x"
                app.SpeedButton.Text = '.9x';
                app.play_speed = .1;
            elseif speed == ".9x"
                app.SpeedButton.Text = '.8x';
                app.play_speed = .2;
            elseif speed == ".8x"
                app.SpeedButton.Text = '1x';
                app.play_speed = 0;
            end
        end

        % Selection changed function: WaveconditionsButtonGroup
        function WaveconditionsButtonGroupSelectionChanged(app, event)
            %selectedButton = app.WaveconditionsButtonGroup.SelectedObject;
            clear app.data;
            app.Exportable = false;
            app.Loaded = false;
            app.LoadButton.Text = 'Load';
            app.LoadButton.Enable = true;
            UpdateDetails(app);
        end

        % Value changed function: XSensorsEditField
        function XSensorsEditFieldValueChanged(app, event)
            %value = app.XSensorsEditField.Value;
            app.ExportButton.Enable = false;
            app.Exportable = false;
            UpdateDetails(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.Loaded == false
                app.LoadButton.Enable = false;
                EnableButtons(app,false);
                app.LoadButton.Text = "Loading";
                %app.LoadButton.BackgroundColor = [1,1,.07];
                app.LoadButton.BackgroundColor = 'y';
                drawnow;
                %Carga los datos del caso
                app.data = load(app.caso_nom);
                
                EnableButtons(app,true);
                app.LoadButton.BackgroundColor = [0.96,0.96,0.96];
                app.LoadButton.Text = "Loaded";
                string = app.DetailsLabel.Text;
                %app.DetailsLabel.Text= sprintf([string,'\n','Loaded']);
                drawnow;
                app.Loaded = true;
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            open('Help/Experiment4.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Numerical Wave Lab - Experiment 4';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CaseinformationPanel = uipanel(app.UIFigure);
            app.CaseinformationPanel.Title = 'Case information:';
            app.DetailsLabel = uilabel(app.CaseinformationPanel);
            app.DetailsLabel.Text = 'H=';
            app.TituloLabel = uilabel(app.CaseinformationPanel);
            app.TituloLabel.Text = 'Test:';
            app.Image = uiimage(app.CaseinformationPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'help_bt.png';
            app.Timer = uilabel(app.CaseinformationPanel);
            app.Timer.Text = 't= 0 s';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'play_bt.png';
            app.StartButton.Text = '';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Icon = 'skip_bt.png';
            app.SkipButton.Text = '';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Wave reflection from beaches';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Icon = 'pause_bt.png';
            app.PauseButton.Text = '';
            app.SpeedButton = uibutton(app.UIFigure, 'push');
            app.SpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedButtonPushed, true);
            app.SpeedButton.Text = '1x';
            app.WaveconditionsButtonGroup = uibuttongroup(app.UIFigure);
            app.WaveconditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaveconditionsButtonGroupSelectionChanged, true);
            app.WaveconditionsButtonGroup.Title = 'Wave conditions:';
            app.Test1Button = uiradiobutton(app.WaveconditionsButtonGroup);
            app.Test1Button.Text = 'Test 1';
            app.Test1Button.Value = true;
            app.Test2Button = uiradiobutton(app.WaveconditionsButtonGroup);
            app.Test2Button.Text = 'Test 2';
            app.Test3Button = uiradiobutton(app.WaveconditionsButtonGroup);
            app.Test3Button.Text = 'Test 3';
            app.ParticlePanel = uipanel(app.UIFigure);
            app.ParticlePanel.Title = 'WG Cart velocity displacement setting:';
            app.WGCartVelocityDisplacementforx20mLabel = uilabel(app.ParticlePanel);
            app.WGCartVelocityDisplacementforx20mLabel.Text = {'WG Cart Velocity   '; 'Displacement for x<20 m ='};
            app.XSensorsEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.XSensorsEditField.Limits = [0.01 1];
            app.XSensorsEditField.ValueDisplayFormat = '%.2f';
            app.XSensorsEditField.ValueChangedFcn = createCallbackFcn(app, @XSensorsEditFieldValueChanged, true);
            app.XSensorsEditField.Value = 0.5;
            app.xmLabel_4 = uilabel(app.ParticlePanel);
            app.xmLabel_4.Text = 'm/s';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wave Flume')
            xlabel(app.UIAxes, 'Along-flume distance, x (m)')
            ylabel(app.UIAxes, 'z (m)')
            app.WGAxes = uiaxes(app.UIFigure);
            title(app.WGAxes, 'Free surface elevation')
            xlabel(app.WGAxes, 'Time (s)')
            ylabel(app.WGAxes, '\eta (m)')
            app.WGAxes.YLim = [-0.15 0.15];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end